classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ImportwavfileButton    matlab.ui.control.StateButton
        UIAxes                 matlab.ui.control.UIAxes
        volume                 matlab.ui.control.Knob
        VolumeLabel            matlab.ui.control.Label
        FSKnob                 matlab.ui.control.Knob
        FSLabel                matlab.ui.control.Label
        FS_label               matlab.ui.control.Label
        volume_num             matlab.ui.control.Label
        PlayButton             matlab.ui.control.Button
        PauseButton            matlab.ui.control.Button
        Knob                   matlab.ui.control.DiscreteKnob
        ExportwavfileButton    matlab.ui.control.Button
        Switch                 matlab.ui.control.ToggleSwitch
        RecordButton           matlab.ui.control.Button
        Lamp                   matlab.ui.control.Lamp
        bSlider                matlab.ui.control.Slider
        Slider                 matlab.ui.control.Slider
        Slider_2               matlab.ui.control.Slider
        Slider_3               matlab.ui.control.Slider
        NumberofelementsLabel  matlab.ui.control.Label
        WnLabel                matlab.ui.control.Label
        LengthLabel            matlab.ui.control.Label
        StepSizeLabel          matlab.ui.control.Label
        Knob_2                 matlab.ui.control.DiscreteKnob
        DESIGNLabel            matlab.ui.control.Label
        N_BitLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        y = 0 ;
        Fs = 0 ;
        recording = false;
        myVoice ;
        NBit = 8;
        y_filered = 0;
        y_notfilterd = 0 ;
        b = 12 ;
        Wn = 0.3 ;
        L = 13 ;
        IDN = 0.8 ;
        designs = 'low'
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ImportwavfileButton
        function ImportwavfileButtonValueChanged(app, event)
            %             value = app.ImportwavfileButton.Value;
            app.Switch.Value = "Not filtered" ;
            wavFile = uigetfile('*.mp3') ;
            app.y = 0 ;
            if wavFile
                audioread(wavFile)
                [app.y, app.Fs] = audioread(wavFile);
                app.y = app.y(:,1) ;
                app.FSKnob.Value = app.Fs;
                app.volume.Value = 1;
                app.FS_label.Text = string(app.Fs) ;
                app.volume_num.Text = string(app.volume.Value );
            end
            hold(app.UIAxes,"off")
            cla(app.UIAxes)
            d = fir1(app.b,app.Wn,app.designs);
            b = filter(d,1,app.y);
            lms = dsp.LMSFilter(app.L , 'StepSize' , app.IDN );
            [app.y_filered , e , w] = step(lms,app.y,b) ;
            hold(app.UIAxes , "off")
            plot(app.UIAxes,app.y ,'r')
            hold(app.UIAxes , "on")
            plot(app.UIAxes,app.y_filered , 'b');
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            
            cla(app.UIAxes)
            if app.Lamp.Color == [0.00 1.00 0.00]
                app.y = 0 ;
                app.recording = true ;
                app.Lamp.Color = [1.00 0.00 0.00];
                app.RecordButton.Text = 'Stop';
                app.Switch.Value = 'Not filtered' ;
                app.RecordButton.FontColor = 'r' ;
                app.myVoice = audiorecorder(app.FSKnob.Value,app.NBit,1);
                record(app.myVoice);


                recObj = audiorecorder(4000,8,1) ;
                %                 maxy= 0.00001 ;
                %                 j = 1;
                %                 min_ax = 1 ;
                gg = 1 ;
                while app.RecordButton.Text == "Stop"
                    
                    recordblocking(recObj, 0.04);
                    yey = getaudiodata(recObj);
                    %                     if max(abs(yey)) > maxy
                    %                         maxy = max(yey) ;
                    %
                    %                     end
                    plot(app.UIAxes ,yey,'white','linewidth' , 3)
                    hold(app.UIAxes,"on")
                    plot(app.UIAxes ,yey ,'oblue','MarkerSize' , 6,'MarkerFaceColor','red')
                    pause(0.000001)
                    hold(app.UIAxes,"off")
                    
                    %                     for i = 20 :1: length(yey)-1
                    %
                    %                         if app.RecordButton.Text == "Stop"
                    %                             %         fff=i ;
                    %                             g(1) = yey(i);
                    %                             g(2) = yey(i+1);
                    %
                    %                             ii(1) = gg+30 ;
                    %                             ii(2) = gg+1+30 ;
                    %                             gg = gg+1 ;
                    %                             plot(app.UIAxes,ii , g,'b' , 'linewidth' , 2)
                    %
                    %                             xlim(app.UIAxes, [min_ax length(yey)+min_ax+20])
                    %                             ylim(app.UIAxes, [-50/1000 50/1000])
                    %                             hold (app.UIAxes, 'on')
                    %                             pause(0.0000001)
                    %                             if j == 2
                    %                                 min_ax = min_ax + 1;
                    %                             end
                    %                         end
                    %                         g = 0 ;
                    %                         ii = 0 ;
                    %                         j = 2 ;
                    %                     end
                    
                end


            else
                %                 cla reset
                
                app.recording = false ;
                app.Lamp.Color = [0.00 1.00 0.00] ;
                app.RecordButton.Text = 'Record';
                app.RecordButton.FontColor = 'black' ;
                stop(app.myVoice)
                app.y = getaudiodata(app.myVoice) ;
                d = fir1(12,0.3,'low');
                b = filter(d,1,app.y);
                lms = dsp.LMSFilter(13 , 'StepSize' , 0.8 );
                [app.y_filered , e , w] = step(lms,app.y,b) ;
                pause(0.5)
                hold(app.UIAxes,"off")
                cla(app.UIAxes)
                
                plot(app.UIAxes,app.y ,'r')
                hold(app.UIAxes , "on")
                plot(app.UIAxes,app.y_filered , 'b') ;
            end
        end

        % Value changing function: FSKnob
        function FSKnobValueChanging(app, event)
            changingValue = event.Value ;
            app.FS_label.Text =string(changingValue) ;
            d = fir1(app.b,app.Wn,app.designs);
            b = filter(d,1,app.y);
            lms = dsp.LMSFilter(app.L , 'StepSize' , app.IDN );
            [app.y_filered , e , w] = step(lms,app.y,b) ;
            hold(app.UIAxes , "off")
            plot(app.UIAxes,app.y ,'r')
            hold(app.UIAxes , "on")
            plot(app.UIAxes,app.y_filered , 'b');
            
        end

        % Value changing function: volume
        function volumeValueChanging(app, event)
            changingValue = event.Value ;
            app.volume_num.Text = string(changingValue) ;
            
        end

        % Callback function
        function PlayerButtonGroupSelectionChanged(app, event)
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            sound(app.y*app.volume.Value,app.FSKnob.Value)
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            app.NBit = str2num(value) ;
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            clear sound
        end

        % Button pushed function: ExportwavfileButton
        function ExportwavfileButtonPushed(app, event)
            [file,name,path] = uiputfile('*.*','File Selection','Sound.mp3');
            audiowrite(sprintf('%s.%s',name,file),app.y*app.volume.Value,round(app.FSKnob.Value));
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value ;
            if value == "Filtered"
                yyy = app.y ;
                app.y =app.y_filered ;
                app.y_notfilterd = yyy ;
            else
                yyy = app.y ;
                app.y =app.y_notfilterd ;
                app.y_filered = yyy ;
                
            end
        end

        % Value changing function: bSlider
        function bSliderValueChanging(app, event)
            changingValue = event.Value;
            app.b = round(changingValue)
            d = fir1(app.b,app.Wn,app.designs);
            b = filter(d,1,app.y);
            lms = dsp.LMSFilter(app.L , 'StepSize' , app.IDN );
            [app.y_filered , e , w] = step(lms,app.y,b) ;
            hold(app.UIAxes , "off")
            plot(app.UIAxes,app.y ,'r')
            hold(app.UIAxes , "on")
            plot(app.UIAxes,app.y_filered , 'b');
            
        end

        % Callback function
        function bSliderValueChanging2(app, event)
  
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Wn = changingValue
            d = fir1(app.b,app.Wn,app.designs);
            b = filter(d,1,app.y);
            lms = dsp.LMSFilter(app.L , 'StepSize' , app.IDN );
            [app.y_filered , e , w] = step(lms,app.y,b) ;
            hold(app.UIAxes , "off")
            plot(app.UIAxes,app.y ,'r')
            hold(app.UIAxes , "on")
            plot(app.UIAxes,app.y_filered , 'b');
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.L = round(changingValue)
            d = fir1(app.b,app.Wn,app.designs);
            b = filter(d,1,app.y);
            lms = dsp.LMSFilter(app.L , 'StepSize' , app.IDN );
            [app.y_filered , e , w] = step(lms,app.y,b) ;
            hold(app.UIAxes , "off")
            plot(app.UIAxes,app.y ,'r')
            hold(app.UIAxes , "on")
            plot(app.UIAxes,app.y_filered , 'b');
        end

        % Value changing function: Slider_3
        function Slider_3ValueChanging(app, event)
            changingValue = event.Value;
            app.IDN = changingValue
            d = fir1(app.b,app.Wn,app.designs);
            b = filter(d,1,app.y);
            lms = dsp.LMSFilter(app.L , 'StepSize' , app.IDN );
            [app.y_filered , e , w] = step(lms,app.y,b) ;
            hold(app.UIAxes , "off")
            plot(app.UIAxes,app.y ,'r')
            hold(app.UIAxes , "on")
            plot(app.UIAxes,app.y_filered , 'b');
        end

        % Value changed function: Knob_2
        function Knob_2ValueChanged(app, event)
            value = app.Knob_2.Value;
            app.designs = value ;
            d = fir1(app.b,app.Wn,app.designs);
            b = filter(d,1,app.y);
            lms = dsp.LMSFilter(app.L , 'StepSize' , app.IDN );
            [app.y_filered , e , w] = step(lms,app.y,b) ;
            hold(app.UIAxes , "off")
            plot(app.UIAxes,app.y ,'r')
            hold(app.UIAxes , "on")
            plot(app.UIAxes,app.y_filered , 'b');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ImportwavfileButton = uibutton(app.UIFigure, 'state');
            app.ImportwavfileButton.ValueChangedFcn = createCallbackFcn(app, @ImportwavfileButtonValueChanged, true);
            app.ImportwavfileButton.Icon = 'file_audio_wav_15037.png';
            app.ImportwavfileButton.Text = 'Import wav file';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.volume = uiknob(app.UIFigure, 'continuous');
            app.volume.ValueChangingFcn = createCallbackFcn(app, @volumeValueChanging, true);
            app.volume.Value = 10;
            app.VolumeLabel = uilabel(app.UIFigure);
            app.VolumeLabel.Text = 'Volume = ';
            app.FSKnob = uiknob(app.UIFigure, 'continuous');
            app.FSKnob.Limits = [6000 100000];
            app.FSKnob.ValueChangingFcn = createCallbackFcn(app, @FSKnobValueChanging, true);
            app.FSKnob.Value = 8000;
            app.FSLabel = uilabel(app.UIFigure);
            app.FSLabel.Text = 'FS = ';
            app.FS_label = uilabel(app.UIFigure);
            app.FS_label.Text = '8000';
            app.volume_num = uilabel(app.UIFigure);
            app.volume_num.Text = '10';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = '1486348532-music-play-pause-control-go-arrow_80458.png';
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Icon = '1486348534-music-pause-stop-control-play_80459.png';
            app.PauseButton.Text = 'Pause';
            app.Knob = uiknob(app.UIFigure, 'discrete');
            app.Knob.Items = {'8', '16', '24'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = '8';
            app.ExportwavfileButton = uibutton(app.UIFigure, 'push');
            app.ExportwavfileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportwavfileButtonPushed, true);
            app.ExportwavfileButton.Icon = 'wav-file-type-black-interface-symbol_icon-icons.com_57624.png';
            app.ExportwavfileButton.Text = 'Export wav file';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.Items = {'Not filtered', 'Filtered'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Not filtered';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.Lamp = uilamp(app.UIFigure);
            app.bSlider = uislider(app.UIFigure);
            app.bSlider.Limits = [1 100];
            app.bSlider.ValueChangingFcn = createCallbackFcn(app, @bSliderValueChanging, true);
            app.bSlider.Value = 12;
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0.01 0.99];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 0.3;
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [1 100];
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_2.Value = 12;
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [0.01 1];
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            app.Slider_3.Value = 0.8;
            app.NumberofelementsLabel = uilabel(app.UIFigure);
            app.NumberofelementsLabel.Text = 'Number of elements';
            app.WnLabel = uilabel(app.UIFigure);
            app.WnLabel.Text = 'Wn';
            app.LengthLabel = uilabel(app.UIFigure);
            app.LengthLabel.Text = 'Length';
            app.StepSizeLabel = uilabel(app.UIFigure);
            app.StepSizeLabel.Text = 'Step Size';
            app.Knob_2 = uiknob(app.UIFigure, 'discrete');
            app.Knob_2.Items = {'low', 'high', 'bandpass', 'stop', 'DC-1', 'DC-0'};
            app.Knob_2.ValueChangedFcn = createCallbackFcn(app, @Knob_2ValueChanged, true);
            app.Knob_2.Value = 'low';
            app.DESIGNLabel = uilabel(app.UIFigure);
            app.DESIGNLabel.Text = 'DESIGN';
            app.N_BitLabel = uilabel(app.UIFigure);
            app.N_BitLabel.Text = 'N_Bit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end